classdef DT_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TrybpracyPanel              matlab.ui.container.Panel
        OnlineSwitch                matlab.ui.control.Switch
        PoczeniezfizycznymbliniakiemSwitchLabel  matlab.ui.control.Label
        TrybSwitch                  matlab.ui.control.Switch
        TrybSwitchLabel             matlab.ui.control.Label
        WykresyPanel                matlab.ui.container.Panel
        A3CheckBox                  matlab.ui.control.CheckBox
        A2CheckBox                  matlab.ui.control.CheckBox
        A1CheckBox                  matlab.ui.control.CheckBox
        water_levels_plot           matlab.ui.control.UIAxes
        AlarmyPanel                 matlab.ui.container.Panel
        ZaworyZamknieteText         matlab.ui.control.Label
        ZaworyZamknieteLamp         matlab.ui.control.Lamp
        NiskiPoziomText             matlab.ui.control.Label
        NiskiPoziomLamp             matlab.ui.control.Lamp
        PrzelanieText               matlab.ui.control.Label
        PrzelanieLamp               matlab.ui.control.Lamp
        SterowaniePanel             matlab.ui.container.Panel
        sterowaniepompyEditField    matlab.ui.control.NumericEditField
        sterowaniepompyLabel        matlab.ui.control.Label
        sterowanieR2EditField       matlab.ui.control.NumericEditField
        sterowanieR2EditFieldLabel  matlab.ui.control.Label
        sterowanieR1EditField       matlab.ui.control.NumericEditField
        sterowanieR1EditFieldLabel  matlab.ui.control.Label
        R7Switch                    matlab.ui.control.Switch
        R7SwitchLabel               matlab.ui.control.Label
        R4Switch                    matlab.ui.control.Switch
        R4SwitchLabel               matlab.ui.control.Label
        R6Switch                    matlab.ui.control.Switch
        R6SwitchLabel               matlab.ui.control.Label
        R5Switch                    matlab.ui.control.Switch
        R5SwitchLabel               matlab.ui.control.Label
        R3Switch                    matlab.ui.control.Switch
        R3SwitchLabel               matlab.ui.control.Label
        WizualizacjaPanel           matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        Image3                      matlab.ui.control.Image
        R3Label                     matlab.ui.control.Label
        R5Label                     matlab.ui.control.Label
        R1Label_2                   matlab.ui.control.Label
        R4Label                     matlab.ui.control.Label
        R2Label                     matlab.ui.control.Label
        R6Label                     matlab.ui.control.Label
        R7Label                     matlab.ui.control.Label
        Image_R6_green              matlab.ui.control.Image
        Image_R8_green              matlab.ui.control.Image
        Image_R8_red                matlab.ui.control.Image
        Image_R6_red                matlab.ui.control.Image
        Image_R2                    matlab.ui.control.Image
        Image_R5_green              matlab.ui.control.Image
        Image_R5_red                matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        Image_R1                    matlab.ui.control.Image
        Image_R7_green              matlab.ui.control.Image
        Image_R7_red                matlab.ui.control.Image
        Pipe_R1_vertical2           matlab.ui.control.UIAxes
        Pipe_R1_vertical3           matlab.ui.control.UIAxes
        Pipe_R2_horizontal          matlab.ui.control.UIAxes
        Pipe_R2_horizontal2         matlab.ui.control.UIAxes
        Pipe_R3_vertical            matlab.ui.control.UIAxes
        Pipe_R3_vertical2           matlab.ui.control.UIAxes
        Pipe_R1_horizontal          matlab.ui.control.UIAxes
        Pipe_R1_vertical            matlab.ui.control.UIAxes
        Pipe_R8_horizontal          matlab.ui.control.UIAxes
        Pipe_R8_vertical            matlab.ui.control.UIAxes
        Pipe_R8_horizontal2         matlab.ui.control.UIAxes
        Pipe_R7_vertical            matlab.ui.control.UIAxes
        Pipe_R7_horizontal2         matlab.ui.control.UIAxes
        Pipe_R7_horizontal          matlab.ui.control.UIAxes
        Pipe_R2_horizontal3         matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_4                    matlab.ui.control.UIAxes
        Pipe_pump                   matlab.ui.control.UIAxes
        Pipe_pump2                  matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        ModelName string = "DT" % Nazwa twojego modelu .slx

        % Uchwyty do obiektów graficznych (naszych słupków)
        TankA1_WaterPatch
        TankA2_WaterPatch
        TankA3_WaterPatch

        UpdateTimer % Uchwyt do obiektu timera

        PlotLineA1   % Handle for the plot line for Tank A1
        PlotLineA2   % Handle for the plot line for Tank A2
        PlotLineA3   % Handle for the plot line for Tank A3
        
        TimeHistory = [] % Array to store time data
        A1_History = []  % Array to store level data for A1
        A2_History = []  % Array to store level data for A2
        A3_History = []  % Array to store level data for A3
        
        MaxHistoryPoints = 1200 % How many points to show (e.g., 20 seconds at 10Hz)
    end
    
    methods (Access = private)

        % Funkcja aktualizująca GUI wywoływana cyklicznie przez timer
        function updateGUI(app)
            try
                if strcmp(get_param(app.ModelName, 'SimulationStatus'), 'running')
                    
                    % Odczytujemy dane bezpośrednio z portów wyjściowych bloków,
                    % które generują wartości, tak jak robi to Scope.
                    
                    % Ścieżka do bloku generującego poziom A1
                    block_path_A1 = app.ModelName + "/Poziom w A1/Divide3";
                    val_A1 = get_param(block_path_A1, 'RuntimeObject').OutputPort(1).Data;
                    %disp(val_A1)
        
                    % Ścieżka do bloku generującego poziom A2
                    block_path_A2 = "DT/Poziom w A2/Divide4";
                    val_A2 = get_param(block_path_A2, 'RuntimeObject').OutputPort(1).Data;
                    %disp(val_A2)
                    
                    % Ścieżka do bloku generującego poziom A3 (zbiornik odpływowy)
                    block_path_A3 = "DT/Poziom w zbiorniku odplywowym/Divide1";
                    val_A3 = get_param(block_path_A3, 'RuntimeObject').OutputPort(1).Data;
                    %disp(val_A3)
                    %disp('------------------------')
                    if val_A3 > 10
                        app.NiskiPoziomLamp.Visible = "off";
                        app.NiskiPoziomText.Visible = "off";
                    else
                        app.NiskiPoziomLamp.Visible = "on";
                        app.NiskiPoziomText.Visible = "on";
                    end


                    % Ścieżka do bloku AND z warunkiem zadziałania
                    % zabezpieczenia
                    block_path_A3 = "DT/zabezpieczenie przed przelaniem/AND";
                    pump_stop = get_param(block_path_A3, 'RuntimeObject').OutputPort(1).Data;
                    %disp(pump_stop)

                    % wyświetlenie informacji o zadziałaniu zabezpieczenia

                    if pump_stop == 1
                        app.PrzelanieLamp.Visible = "off";
                        app.PrzelanieText.Visible = "off";
                    else
                        app.PrzelanieLamp.Visible = "on";
                        app.PrzelanieText.Visible = "on";
                    end

                    % alarm - pompa włączona i zawory pozamykane
                    if app.sterowaniepompyEditField.Value > 0 && app.R3Switch.Value == "Off" && (app.R6Switch.Value == "off" || app.sterowanieR2EditField.Value <= 30)
                        app.ZaworyZamknieteLamp.Visible = "on";
                        app.ZaworyZamknieteText.Visible = "on";
                    else
                        app.ZaworyZamknieteLamp.Visible = "off";
                        app.ZaworyZamknieteText.Visible = "off";
                    end


                    % Ten fragment pozostaje bez zmian
                    app.TankA1_WaterPatch.YData = [0, 0, val_A1, val_A1];
                    app.TankA2_WaterPatch.YData = [0, 0, val_A2, val_A2];
                    app.TankA3_WaterPatch.YData = [0, 0, val_A3, val_A3];

                    % 1. Get current simulation time
                    currentTime = get_param(app.ModelName, 'SimulationTime');
                    
                    % 2. Append new data to history arrays
                    app.TimeHistory = [app.TimeHistory, currentTime];
                    app.A1_History  = [app.A1_History,  val_A1];
                    app.A2_History  = [app.A2_History,  val_A2];
                    app.A3_History  = [app.A3_History,  val_A3];


                    % 3. Trim history to create a scrolling plot
                    if length(app.TimeHistory) > app.MaxHistoryPoints
                        % Keep only the last MaxHistoryPoints
                        app.TimeHistory = app.TimeHistory(end-app.MaxHistoryPoints+1:end);
                        app.A1_History  = app.A1_History(end-app.MaxHistoryPoints+1:end);
                        app.A2_History  = app.A2_History(end-app.MaxHistoryPoints+1:end);
                        app.A3_History  = app.A3_History(end-app.MaxHistoryPoints+1:end);
                    end

                    %display(app.A1_History)
                    %display(isgraphics(app.PlotLineA1));
                    
                    % 4. Update the plot lines with new data (this is very fast)
                    set(app.PlotLineA1, 'XData', app.TimeHistory, 'YData', app.A1_History);
                    set(app.PlotLineA2, 'XData', app.TimeHistory, 'YData', app.A2_History);
                    set(app.PlotLineA3, 'XData', app.TimeHistory, 'YData', app.A3_History);
                    % 5. Auto-scale the X-axis
                    if ~isempty(app.TimeHistory)
                        % Make the plot scroll
                        app.water_levels_plot.XLim = [app.TimeHistory(1), app.TimeHistory(end)+1]; % +1 to add a little space
                    end
                else
                    if ~isempty(app.UpdateTimer) && isvalid(app.UpdateTimer)
                        disp('Symulacja została zatrzymana. Zatrzymuję timer.');
                        stop(app.UpdateTimer);
                    end
                end


                if strcmp(app.TrybSwitch.Value, 'Monitorowanie') && strcmp(app.OnlineSwitch.Value, 'Online')
                    block_path_pompa = 'DT/Tryb online/Tryb monitorowania/Gain';
                    block_path_R1 = 'DT/Tryb online/Tryb monitorowania/Gain1';
                    block_path_R2 = 'DT/Tryb online/Tryb monitorowania/Gain2';
                    app.sterowaniepompyEditField.Value = get_param(block_path_pompa, 'RuntimeObject').OutputPort(1).Data;
                    app.sterowanieR1EditField.Value = get_param(block_path_R1, 'RuntimeObject').OutputPort(1).Data;
                    app.sterowanieR2EditField.Value = get_param(block_path_R2, 'RuntimeObject').OutputPort(1).Data;
                end
            catch ME
                if ~isempty(app.UpdateTimer) && isvalid(app.UpdateTimer)
                    stop(app.UpdateTimer);
                end
                fprintf('!!! WYSTĄPIŁ BŁĄD Wewnątrz updateGUI !!!\n');
                disp(ME.getReport('extended'));
            end
        end
    
            % Funkcja rysująca jeden zbiornik
            function waterPatch = createTank(app, targetAxes, titleText)
                % Rysuj szare tło (kontener)
                bg = patch(targetAxes, [0 1 1 0], [0 0 100 100], [0.8 0.8 0.8]);
                hold(targetAxes, 'on');
            
                % Rysuj niebieskie wypełnienie na poziomie 0
                waterPatch = patch(targetAxes, [0 1 1 0], [0 0 0 0], 'blue');
                hold(targetAxes, 'off');
            
                % Ustawienia osi
                targetAxes.YLim = [0 100];
                targetAxes.XLim = [-0.1 1.1];
                targetAxes.XTick = [];
                %ylabel(targetAxes, '%');
                title(targetAxes, titleText);
                %grid(targetAxes, 'on');

                targetAxes.Color = "None";
                targetAxes.XColor = "None";
                targetAxes.YColor = "None";
            end
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load("macierze_do_modelu.mat")
            
            %app.UIFigure.WindowState = 'maximized';
            app.TankA1_WaterPatch = createTank(app, app.UIAxes_2, 'Zbiornik A1');
            app.TankA2_WaterPatch = createTank(app, app.UIAxes, 'Zbiornik A2');
            app.TankA3_WaterPatch = createTank(app, app.UIAxes_4, 'Zbiornik A3 (Odpływowy)');
    
            try
                if ~bdIsLoaded(app.ModelName)
                    load_system(app.ModelName);
                end
                
                disp('Łączenie z modelem w trybie running...');
                set_param(app.ModelName, 'SimulationCommand', 'connect');
                
                pause(2.0);
                
                disp('Wydaję polecenie startu...');
                set_param(app.ModelName, 'SimulationCommand', 'start');
                
                disp('Uruchamiam timer do monitorowania startu i aktualizacji GUI...');
                app.UpdateTimer = timer(...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 0.1, ...
                    'TimerFcn', @(~,~) app.updateGUI());
                start(app.UpdateTimer);
    
            catch ME
                uialert(app.UIFigure, ME.message, 'Błąd podczas uruchamiania symulacji');
            end

            % --- Initialize the Switch for R3 ---
    try
        % Try to read the value of 'R3' from the base workspace
        r3_value = evalin('base', 'R3');
        
        % Update the switch based on the value
        if r3_value
            app.R3Switch.Value = 'On';
            app.Image.Visible = 'On';
            app.Image2.Visible = 'Off';
        else
            app.R3Switch.Value = 'Off';
            app.Image.Visible = 'Off';
            app.Image2.Visible = 'On';
        end
        disp('Successfully initialized R3 switch from workspace.');
        
    catch
        % If 'R3' doesn't exist, create it with a default value (false)
        disp('Warning: Variable R3 not found. Creating it with default value: false.');
        assignin('base', 'R3', false);
        app.R3Switch.Value = 'Off'; % Set the switch to the default
    end

    % --- Initialize the Switch for R5 ---
    try
        % Try to read the value of 'R5' from the base workspace
        r5_value = evalin('base', 'R5');
        
        % Update the switch based on the value
        if r5_value
            app.R5Switch.Value = 'On';
            app.Image_R5_green.Visible = 'On';
            app.Image_R5_red.Visible = 'Off';
        else
            app.R5Switch.Value = 'Off';
            app.Image_R5_green.Visible = 'off';
            app.Image_R5_red.Visible = 'on';
        end
        disp('Successfully initialized R5 switch from workspace.');
        
    catch
        % If 'R5' doesn't exist, create it with a default value (false)
        disp('Warning: Variable R5 not found. Creating it with default value: false.');
        assignin('base', 'R5', false);
        app.R5Switch.Value = 'Off'; % Set the switch to the default
    end

    % --- Initialize the Switch for R6 ---
    try
        % Try to read the value of 'R6' from the base workspace
        r6_value = evalin('base', 'R6');
        
        % Update the switch based on the value
        if r6_value
            app.R6Switch.Value = 'On';
            app.Image_R6_green.Visible = 'on';
            app.Image_R6_red.Visible = 'off';
        else
            app.R6Switch.Value = 'Off';
            app.Image_R6_green.Visible = 'off';
            app.Image_R6_red.Visible = 'on';
        end
        disp('Successfully initialized R6 switch from workspace.');
        
    catch
        % If 'R6' doesn't exist, create it with a default value (false)
        disp('Warning: Variable R6 not found. Creating it with default value: false.');
        assignin('base', 'R6', false);
        app.R6Switch.Value = 'Off'; % Set the switch to the default
    end

    % --- Initialize the Switch for 4 ---
    try
        % Try to read the value of 'R4' from the base workspace
        r4_value = evalin('base', 'R4');
        
        % Update the switch based on the value
        if r4_value
            app.R4Switch.Value = 'On';
            app.Image_R7_green.Visible = 'on';
            app.Image_R7_red.Visible = 'off';
        else
            app.R4Switch.Value = 'Off';
            app.Image_R7_green.Visible = 'off';
            app.Image_R7_red.Visible = 'on';
        end
        disp('Successfully initialized R7 switch from workspace.');
        
    catch
        % If 'R7' doesn't exist, create it with a default value (false)
        disp('Warning: Variable R4 not found. Creating it with default value: false.');
        assignin('base', 'R4', false);
        app.R4Switch.Value = 'Off'; % Set the switch to the default
    end

    % --- Initialize the Switch for R7 ---
    try
        % Try to read the value of 'R7' from the base workspace
        r7_value = evalin('base', 'R7');
        
        % Update the switch based on the value
        if r7_value
            app.R7Switch.Value = 'On';
            app.Image_R8_green.Visible = 'on';
            app.Image_R8_red.Visible = 'off';
        else
            app.R7Switch.Value = 'Off';
            app.Image_R8_green.Visible = 'off';
            app.Image_R8_red.Visible = 'on';
        end
        disp('Successfully initialized R8 switch from workspace.');
        
    catch
        % If 'R8' doesn't exist, create it with a default value (false)
        disp('Warning: Variable R7 not found. Creating it with default value: false.');
        assignin('base', 'R7', false);
        app.R7Switch.Value = 'Off'; % Set the switch to the default
    end

    % --- Initialize the Numeric Edit Field for R1_sterowanie ---
    try
        % Try to read the value of 'R1_sterowanie' from the base workspace
        r1_value = evalin('base', 'R1_sterowanie');
        
        % Update the numeric field with the value
        app.sterowanieR1EditField.Value = r1_value;
        disp('Successfully initialized R1_sterowanie field from workspace.');

    catch
        % If 'R1_sterowanie' doesn't exist, create it with a default (e.g., 0)
        disp('Warning: Variable R1_sterowanie not found. Creating it with default value: 0.');
        assignin('base', 'R1_sterowanie', 0);
        app.sterowanieR1EditField.Value = 0; % Set the field to the default
    end

    % --- Initialize the Numeric Edit Field for R2_sterowanie ---
    try
        % Try to read the value of 'R2_sterowanie' from the base workspace
        r2_value = evalin('base', 'R2_sterowanie');
        
        % Update the numeric field with the value
        app.sterowanieR2EditField.Value = r2_value;
        disp('Successfully initialized R2_sterowanie field from workspace.');

    catch
        % If 'R2_sterowanie' doesn't exist, create it with a default (e.g., 0)
        disp('Warning: Variable R2_sterowanie not found. Creating it with default value: 0.');
        assignin('base', 'R2_sterowanie', 0);
        app.sterowanieR2EditField.Value = 0; % Set the field to the default
    end

    % --- Initialize the Numeric Edit Field for pompa_sterowanie ---
    try
        % Try to read the value of 'pompa_sterowanie' from the base workspace
        pompa_value = evalin('base', 'pompa_sterowanie');
        
        % Update the numeric field with the value
        app.sterowaniepompyEditField.Value = pompa_value;
        disp('Successfully initialized pompa_sterowanie field from workspace.');

    catch
        % If 'pompa_sterowanie' doesn't exist, create it with a default (e.g., 0)
        disp('Warning: Variable pompa_sterowanie not found. Creating it with default value: 0.');
        assignin('base', 'pompa_sterowanie', 0);
        app.sterowaniepompyEditField.Value = 0; % Set the field to the default
    end
    
    % --- Drawing Pipes ----
    % --- Draw Pipe Segment in Pipe_H1 ---
    % 1. Draw the line
    line(app.Pipe_R2_horizontal3, [0.23 0.95], [0.5 0.5], 'Color', 'black', 'LineWidth', 1);
    
    % --- Make the Axes Container Invisible ---
    app.Pipe_R2_horizontal3.Color = 'none';      % Make the background transparent
    app.Pipe_R2_horizontal3.XColor = 'none';     % Hide the X-axis line, ticks, and labels
    app.Pipe_R2_horizontal3.YColor = 'none';     % Hide the Y-axis line, ticks, and labels
    
    app.Pipe_R2_horizontal3.XLim = [0 1];
    app.Pipe_R2_horizontal3.YLim = [0 1];

    % R7_horizontal
    line(app.Pipe_R7_horizontal, [0.075 0.895], [0.5 0.5], 'Color', 'black', 'LineWidth', 1);
    app.Pipe_R7_horizontal.Color = 'none';      % Make the background transparent
    app.Pipe_R7_horizontal.XColor = 'none';     % Hide the X-axis line, ticks, and labels
    app.Pipe_R7_horizontal.YColor = 'none';     % Hide the Y-axis line, ticks, and labels
    
    app.Pipe_R7_horizontal.XLim = [0 1];
    app.Pipe_R7_horizontal.YLim = [0 1];

    % R7_horizontal2
    line(app.Pipe_R7_horizontal2, [0.44 0.75], [0.5 0.5], 'Color', 'black', 'LineWidth', 1);
    app.Pipe_R7_horizontal2.Color = 'none';      % Make the background transparent
    app.Pipe_R7_horizontal2.XColor = 'none';     % Hide the X-axis line, ticks, and labels
    app.Pipe_R7_horizontal2.YColor = 'none';     % Hide the Y-axis line, ticks, and labels
    
    app.Pipe_R7_horizontal2.XLim = [0 1];
    app.Pipe_R7_horizontal2.YLim = [0 1];

    % R7_vertical
    line(app.Pipe_R7_vertical, [0.9 0.9], [0.16 0.77], 'Color', 'black', 'LineWidth', 1);
    app.Pipe_R7_vertical.Color = 'none';      % Make the background transparent
    app.Pipe_R7_vertical.XColor = 'none';     % Hide the X-axis line, ticks, and labels
    app.Pipe_R7_vertical.YColor = 'none';     % Hide the Y-axis line, ticks, and labels
    
    app.Pipe_R7_vertical.XLim = [0 1];
    app.Pipe_R7_vertical.YLim = [0 1];

    % R8_horizontal
    line(app.Pipe_R8_horizontal, [0.33 0.57], [0.5 0.5], 'Color', 'black', 'LineWidth', 1);
    app.Pipe_R8_horizontal.Color = 'none';      % Make the background transparent
    app.Pipe_R8_horizontal.XColor = 'none';     % Hide the X-axis line, ticks, and labels
    app.Pipe_R8_horizontal.YColor = 'none';     % Hide the Y-axis line, ticks, and labels
    
    app.Pipe_R8_horizontal.XLim = [0 1];
    app.Pipe_R8_horizontal.YLim = [0 1];

    % R8_horizontal2
    line(app.Pipe_R8_horizontal2, [0.45 0.74], [0.5 0.5], 'Color', 'black', 'LineWidth', 1);
    app.Pipe_R8_horizontal2.Color = 'none';      % Make the background transparent
    app.Pipe_R8_horizontal2.XColor = 'none';     % Hide the X-axis line, ticks, and labels
    app.Pipe_R8_horizontal2.YColor = 'none';     % Hide the Y-axis line, ticks, and labels
    
    app.Pipe_R8_horizontal2.XLim = [0 1];
    app.Pipe_R8_horizontal2.YLim = [0 1];

    % R8_vertical
    line(app.Pipe_R8_vertical, [0.9 0.9], [0.48 0.68], 'Color', 'black', 'LineWidth', 1);
    app.Pipe_R8_vertical.Color = 'none';      % Make the background transparent
    app.Pipe_R8_vertical.XColor = 'none';     % Hide the X-axis line, ticks, and labels
    app.Pipe_R8_vertical.YColor = 'none';     % Hide the Y-axis line, ticks, and labels
    
    app.Pipe_R8_vertical.XLim = [0 1];
    app.Pipe_R8_vertical.YLim = [0 1];

    % R1_vertical
    line(app.Pipe_R1_vertical, [0.5 0.5], [0.06 0.72], 'Color', 'black', 'LineWidth', 1);
    app.Pipe_R1_vertical.Color = 'none';      % Make the background transparent
    app.Pipe_R1_vertical.XColor = 'none';     % Hide the X-axis line, ticks, and labels
    app.Pipe_R1_vertical.YColor = 'none';     % Hide the Y-axis line, ticks, and labels
    
    app.Pipe_R1_vertical.XLim = [0 1];
    app.Pipe_R1_vertical.YLim = [0 1];

    % R1_vertical2
    line(app.Pipe_R1_vertical2, [0.5 0.5], [0.41 0.59], 'Color', 'black', 'LineWidth', 1);
    app.Pipe_R1_vertical2.Color = 'none';      % Make the background transparent
    app.Pipe_R1_vertical2.XColor = 'none';     % Hide the X-axis line, ticks, and labels
    app.Pipe_R1_vertical2.YColor = 'none';     % Hide the Y-axis line, ticks, and labels
    
    app.Pipe_R1_vertical2.XLim = [0 1];
    app.Pipe_R1_vertical2.YLim = [0 1];

    % R1_vertical3
    line(app.Pipe_R1_vertical3, [0.5 0.5], [0.5 0.76], 'Color', 'black', 'LineWidth', 1);
    app.Pipe_R1_vertical3.Color = 'none';      % Make the background transparent
    app.Pipe_R1_vertical3.XColor = 'none';     % Hide the X-axis line, ticks, and labels
    app.Pipe_R1_vertical3.YColor = 'none';     % Hide the Y-axis line, ticks, and labels
    
    app.Pipe_R1_vertical3.XLim = [0 1];
    app.Pipe_R1_vertical3.YLim = [0 1];

    % R1_horizontal
    line(app.Pipe_R1_horizontal, [0.05 0.96], [0.5 0.5], 'Color', 'black', 'LineWidth', 1);
    app.Pipe_R1_horizontal.Color = 'none';      % Make the background transparent
    app.Pipe_R1_horizontal.XColor = 'none';     % Hide the X-axis line, ticks, and labels
    app.Pipe_R1_horizontal.YColor = 'none';     % Hide the Y-axis line, ticks, and labels
    
    app.Pipe_R1_horizontal.XLim = [0 1];
    app.Pipe_R1_horizontal.YLim = [0 1];

    % R2_horizontal
    line(app.Pipe_R2_horizontal, [0.44 0.57], [0.5 0.5], 'Color', 'black', 'LineWidth', 1);
    app.Pipe_R2_horizontal.Color = 'none';      % Make the background transparent
    app.Pipe_R2_horizontal.XColor = 'none';     % Hide the X-axis line, ticks, and labels
    app.Pipe_R2_horizontal.YColor = 'none';     % Hide the Y-axis line, ticks, and labels
    
    app.Pipe_R2_horizontal.XLim = [0 1];
    app.Pipe_R2_horizontal.YLim = [0 1];

    % R2_horizontal2
    line(app.Pipe_R2_horizontal2, [0.13 0.47], [0.5 0.5], 'Color', 'black', 'LineWidth', 1);
    app.Pipe_R2_horizontal2.Color = 'none';      % Make the background transparent
    app.Pipe_R2_horizontal2.XColor = 'none';     % Hide the X-axis line, ticks, and labels
    app.Pipe_R2_horizontal2.YColor = 'none';     % Hide the Y-axis line, ticks, and labels
    
    app.Pipe_R2_horizontal2.XLim = [0 1];
    app.Pipe_R2_horizontal2.YLim = [0 1];

    % R3_vertical
    line(app.Pipe_R3_vertical, [0.5 0.5], [0.5 0.77], 'Color', 'black', 'LineWidth', 1);
    app.Pipe_R3_vertical.Color = 'none';      % Make the background transparent
    app.Pipe_R3_vertical.XColor = 'none';     % Hide the X-axis line, ticks, and labels
    app.Pipe_R3_vertical.YColor = 'none';     % Hide the Y-axis line, ticks, and labels
    
    app.Pipe_R3_vertical.XLim = [0 1];
    app.Pipe_R3_vertical.YLim = [0 1];

    % R3_vertical2
    line(app.Pipe_R3_vertical2, [0.5 0.5], [0.1 0.89], 'Color', 'black', 'LineWidth', 1);
    app.Pipe_R3_vertical2.Color = 'none';      % Make the background transparent
    app.Pipe_R3_vertical2.XColor = 'none';     % Hide the X-axis line, ticks, and labels
    app.Pipe_R3_vertical2.YColor = 'none';     % Hide the Y-axis line, ticks, and labels
    
    app.Pipe_R3_vertical2.XLim = [0 1];
    app.Pipe_R3_vertical2.YLim = [0 1];

    % Pipe_pump
    line(app.Pipe_pump, [0 0.65], [0.65 0.65], 'Color', 'black', 'LineWidth', 1);
    app.Pipe_pump.Color = 'none';      % Make the background transparent
    app.Pipe_pump.XColor = 'none';     % Hide the X-axis line, ticks, and labels
    app.Pipe_pump.YColor = 'none';     % Hide the Y-axis line, ticks, and labels
    
    app.Pipe_pump.XLim = [0 1];
    app.Pipe_pump.YLim = [0 1];

    % Pipe_pump2
    line(app.Pipe_pump2, [0.11 0.96], [0.45 0.45], 'Color', 'black', 'LineWidth', 1);
    app.Pipe_pump2.Color = 'none';      % Make the background transparent
    app.Pipe_pump2.XColor = 'none';     % Hide the X-axis line, ticks, and labels
    app.Pipe_pump2.YColor = 'none';     % Hide the Y-axis line, ticks, and labels
    
    app.Pipe_pump2.XLim = [0 1];
    app.Pipe_pump2.YLim = [0 1];

    app.A1CheckBox.Value = true;
    app.A2CheckBox.Value = true;
    app.A3CheckBox.Value = true;
    
    pause(0.1)
    % Configure the plot's appearance
        hold(app.water_levels_plot, 'on');
        grid(app.water_levels_plot, 'on');
        app.water_levels_plot.YLim = [-5 105]; % Fix Y-axis from 0 to 100
        
    % Initialize the plot lines (draw them empty)
        
        app.PlotLineA1 = line(app.water_levels_plot, 'XData', [], 'YData', [], 'Color', 'b', 'LineWidth', 1);
        app.PlotLineA2 = line(app.water_levels_plot, 'XData', [], 'YData', [], 'Color', 'r', 'LineWidth', 1);
        app.PlotLineA3 = line(app.water_levels_plot, 'XData', [], 'YData', [], 'Color', 'g', 'LineWidth', 1);
        %display(isgraphics(app.PlotLineA1))

        plotHandles = [app.PlotLineA1, app.PlotLineA2, app.PlotLineA3];
        plotLabels = {'Zbiornik A1', 'Zbiornik A2', 'Zbiornik A3'};
        legend(app.water_levels_plot, plotHandles, plotLabels, 'Location', 'northwest');
        hold(app.water_levels_plot, 'off');

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Zatrzymaj i usuń timer
            if ~isempty(app.UpdateTimer) && isvalid(app.UpdateTimer)
                stop(app.UpdateTimer);
                delete(app.UpdateTimer);
            end
            
            % Zatrzymaj symulację za pomocą obiektu symulacji
            try
                disp('Zatrzymywanie symulacji...');
                set_param('DT', 'SimulationCommand', 'stop');
                disp('Symulacja zatrzymana.');
            catch ME
                fprintf('Błąd podczas zatrzymywania symulacji: %s\n', ME.message);
            end
            
            delete(app);
        end

        % Value changed function: R3Switch
        function R3SwitchValueChanged(app, event)
           modelName = 'DT';

            if strcmp(app.R3Switch.Value, 'On')
                newValue = true; % Use logical true/false for boolean
                app.Image.Visible = "on";
                app.Image2.Visible = "off";
            else
                newValue = false;
                app.Image.Visible = "off";
                app.Image2.Visible = "on";
            end
            
            % --- 3. EXECUTE THE "UPDATE FROM WORKSPACE" METHOD ---
            try
                % First, verify the model is actually running in the correct mode
                simStatus = get_param(modelName, 'SimulationStatus');
                if ~strcmp(simStatus, 'running')
                    errorMessage = 'Model is not running in running Mode. This command only works on an active simulation.';
                    uialert(app.UIFigure, errorMessage, 'Error');
                    disp(errorMessage);
                    return; % Stop here
                end
        
                % Change the .Value property of the 'R3' object in the BASE workspace
                % 'assignin' and 'evalin' are needed to escape the app's local workspace
                assignin('base', 'R3', newValue);

                % --- 5. TELL SIMULINK TO UPDATE ITS PARAMETERS ---
                % This command tells the running model to re-read 'R3' from the workspace.
                set_param(modelName, 'SimulationCommand', 'update');
                
                % Give yourself visual confirmation that the command was sent
                disp('Workspace parameter updated and simulation update command sent.');
        
            catch ME
                % If something goes wrong, this will give you a detailed error
                uialert(app.UIFigure, ['An error occurred: ' ME.message], 'Error!');
                fprintf('\n--- APP DESIGNER ERROR ---\n');
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
            end
        end

        % Value changed function: R5Switch
        function R5SwitchValueChanged(app, event)
            modelName = 'DT';

            if strcmp(app.R5Switch.Value, 'On')
                newValue = true; % Use logical true/false for boolean
                app.Image_R5_green.Visible = "on";
                app.Image_R5_red.Visible = "off";
            else
                newValue = false;
                app.Image_R5_green.Visible = "off";
                app.Image_R5_red.Visible = "on";
            end
            
            % --- 3. EXECUTE THE "UPDATE FROM WORKSPACE" METHOD ---
            try
                % First, verify the model is actually running in the correct mode
                simStatus = get_param(modelName, 'SimulationStatus');
                if ~strcmp(simStatus, 'running')
                    errorMessage = 'Model is not running in running Mode. This command only works on an active simulation.';
                    uialert(app.UIFigure, errorMessage, 'Error');
                    disp(errorMessage);
                    return; % Stop here
                end
        
                % Change the .Value property of the 'R5' object in the BASE workspace
                assignin('base', 'R5', newValue);

                % This command tells the running model to re-read 'R5' from the workspace.
                set_param(modelName, 'SimulationCommand', 'update');
                
                disp('Workspace parameter updated and simulation update command sent.');
        
            catch ME
                % If something goes wrong, this will give you a detailed error
                uialert(app.UIFigure, ['An error occurred: ' ME.message], 'Error!');
                fprintf('\n--- APP DESIGNER ERROR ---\n');
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
            end
        end

        % Value changed function: R6Switch
        function R6SwitchValueChanged(app, event)
            modelName = 'DT';

            if strcmp(app.R6Switch.Value, 'On')
                newValue = true; % Use logical true/false for boolean
                app.Image_R6_green.Visible = "on";
                app.Image_R6_red.Visible = "off";
            else
                newValue = false;
                app.Image_R6_green.Visible = "off";
                app.Image_R6_red.Visible = "on";
            end
            
            % --- 3. EXECUTE THE "UPDATE FROM WORKSPACE" METHOD ---
            try
                % First, verify the model is actually running in the correct mode
                simStatus = get_param(modelName, 'SimulationStatus');
                if ~strcmp(simStatus, 'running')
                    errorMessage = 'Model is not running in running Mode. This command only works on an active simulation.';
                    uialert(app.UIFigure, errorMessage, 'Error');
                    disp(errorMessage);
                    return; % Stop here
                end
        
                % Change the .Value property of the 'R6' object in the BASE workspace
                assignin('base', 'R6', newValue);

                % This command tells the running model to re-read 'R6' from the workspace.
                set_param(modelName, 'SimulationCommand', 'update');
                
                disp('Workspace parameter updated and simulation update command sent.');
        
            catch ME
                % If something goes wrong, this will give you a detailed error
                uialert(app.UIFigure, ['An error occurred: ' ME.message], 'Error!');
                fprintf('\n--- APP DESIGNER ERROR ---\n');
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
            end
            
        end

        % Value changed function: R4Switch
        function R4SwitchValueChanged(app, event)
            modelName = 'DT';

            if strcmp(app.R4Switch.Value, 'On')
                newValue = true; % Use logical true/false for boolean
                app.Image_R7_green.Visible = "on";
                app.Image_R7_red.Visible = "off";
            else
                newValue = false;
                app.Image_R7_green.Visible = "off";
                app.Image_R7_red.Visible = "on";
            end
            
            % --- 3. EXECUTE THE "UPDATE FROM WORKSPACE" METHOD ---
            try
                % First, verify the model is actually running in the correct mode
                simStatus = get_param(modelName, 'SimulationStatus');
                if ~strcmp(simStatus, 'running')
                    errorMessage = 'Model is not running in running Mode. This command only works on an active simulation.';
                    uialert(app.UIFigure, errorMessage, 'Error');
                    disp(errorMessage);
                    return; % Stop here
                end
        
                % Change the .Value property of the 'R7' object in the BASE workspace
                assignin('base', 'R4', newValue);

                % This command tells the running model to re-read 'R7' from the workspace.
                set_param(modelName, 'SimulationCommand', 'update');
                
                disp('Workspace parameter updated and simulation update command sent.');
        
            catch ME
                % If something goes wrong, this will give you a detailed error
                uialert(app.UIFigure, ['An error occurred: ' ME.message], 'Error!');
                fprintf('\n--- APP DESIGNER ERROR ---\n');
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
            end
            
        end

        % Value changed function: R7Switch
        function R7SwitchValueChanged(app, event)
            modelName = 'DT';

            if strcmp(app.R7Switch.Value, 'On')
                newValue = true; % Use logical true/false for boolean
                app.Image_R8_green.Visible = "on";
                app.Image_R8_red.Visible = "off";
            else
                newValue = false;
                app.Image_R8_green.Visible = "off";
                app.Image_R8_red.Visible = "on";
            end
            
            % --- 3. EXECUTE THE "UPDATE FROM WORKSPACE" METHOD ---
            try
                % First, verify the model is actually running in the correct mode
                simStatus = get_param(modelName, 'SimulationStatus');
                if ~strcmp(simStatus, 'running')
                    errorMessage = 'Model is not running in running Mode. This command only works on an active simulation.';
                    uialert(app.UIFigure, errorMessage, 'Error');
                    disp(errorMessage);
                    return; % Stop here
                end
        
                % Change the .Value property of the 'R8' object in the BASE workspace
                assignin('base', 'R7', newValue);

                % This command tells the running model to re-read 'R8' from the workspace.
                set_param(modelName, 'SimulationCommand', 'update');
                
                disp('Workspace parameter updated and simulation update command sent.');
        
            catch ME
                % If something goes wrong, this will give you a detailed error
                uialert(app.UIFigure, ['An error occurred: ' ME.message], 'Error!');
                fprintf('\n--- APP DESIGNER ERROR ---\n');
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
            end
            
        end

        % Value changed function: sterowanieR1EditField
        function sterowanieR1EditFieldValueChanged(app, event)
            modelName = 'DT';

            newValue = app.sterowanieR1EditField.Value;
            
            % --- 3. EXECUTE THE "UPDATE FROM WORKSPACE" METHOD ---
            try
                % First, verify the model is actually running in the correct mode
                simStatus = get_param(modelName, 'SimulationStatus');
                if ~strcmp(simStatus, 'running')
                    errorMessage = 'Model is not running in running Mode. This command only works on an active simulation.';
                    uialert(app.UIFigure, errorMessage, 'Error');
                    disp(errorMessage);
                    return; % Stop here
                end
        
                % Change the .Value property of the 'R6' object in the BASE workspace
                assignin('base', 'R1_sterowanie', newValue);

                % This command tells the running model to re-read 'R6' from the workspace.
                set_param(modelName, 'SimulationCommand', 'update');
                
                disp('Workspace parameter updated and simulation update command sent.');
        
            catch ME
                % If something goes wrong, this will give you a detailed error
                uialert(app.UIFigure, ['An error occurred: ' ME.message], 'Error!');
                fprintf('\n--- APP DESIGNER ERROR ---\n');
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
            end
            
        end

        % Value changed function: sterowanieR2EditField
        function sterowanieR2EditFieldValueChanged(app, event)
            modelName = 'DT';

            newValue = app.sterowanieR2EditField.Value;
            
            % --- 3. EXECUTE THE "UPDATE FROM WORKSPACE" METHOD ---
            try
                % First, verify the model is actually running in the correct mode
                simStatus = get_param(modelName, 'SimulationStatus');
                if ~strcmp(simStatus, 'running')
                    errorMessage = 'Model is not running in running Mode. This command only works on an active simulation.';
                    uialert(app.UIFigure, errorMessage, 'Error');
                    disp(errorMessage);
                    return; % Stop here
                end
        
                % Change the .Value property of the 'R6' object in the BASE workspace
                assignin('base', 'R2_sterowanie', newValue);

                % This command tells the running model to re-read 'R6' from the workspace.
                set_param(modelName, 'SimulationCommand', 'update');
                
                disp('Workspace parameter updated and simulation update command sent.');
        
            catch ME
                % If something goes wrong, this will give you a detailed error
                uialert(app.UIFigure, ['An error occurred: ' ME.message], 'Error!');
                fprintf('\n--- APP DESIGNER ERROR ---\n');
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
            end
            
        end

        % Value changed function: sterowaniepompyEditField
        function sterowaniepompyEditFieldValueChanged(app, event)
            modelName = 'DT';

            newValue = app.sterowaniepompyEditField.Value;
            
            % --- 3. EXECUTE THE "UPDATE FROM WORKSPACE" METHOD ---
            try
                % First, verify the model is actually running in the correct mode
                simStatus = get_param(modelName, 'SimulationStatus');
                if ~strcmp(simStatus, 'running')
                    errorMessage = 'Model is not running in running Mode. This command only works on an active simulation.';
                    uialert(app.UIFigure, errorMessage, 'Error');
                    disp(errorMessage);
                    return; % Stop here
                end
        
                % Change the .Value property of the 'R6' object in the BASE workspace
                assignin('base', 'pompa_sterowanie', newValue);

                % This command tells the running model to re-read 'R6' from the workspace.
                set_param(modelName, 'SimulationCommand', 'update');
                
                disp('Workspace parameter updated and simulation update command sent.');
        
            catch ME
                % If something goes wrong, this will give you a detailed error
                uialert(app.UIFigure, ['An error occurred: ' ME.message], 'Error!');
                fprintf('\n--- APP DESIGNER ERROR ---\n');
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
            end
            
        end

        % Value changed function: A1CheckBox
        function A1CheckBoxValueChanged(app, event)
            value = app.A1CheckBox.Value;
            if value == true
                set(app.PlotLineA1, 'Visible', 'On');
            else
                set(app.PlotLineA1, 'Visible', 'Off');
            end
        end

        % Value changed function: A2CheckBox
        function A2CheckBoxValueChanged(app, event)
            value = app.A2CheckBox.Value;
            if value == true
                set(app.PlotLineA2, 'Visible', 'On');
            else
                set(app.PlotLineA2, 'Visible', 'Off');
            end
        end

        % Value changed function: A3CheckBox
        function A3CheckBoxValueChanged(app, event)
            value = app.A3CheckBox.Value;
            if value == true
                set(app.PlotLineA3, 'Visible', 'On');
            else
                set(app.PlotLineA3, 'Visible', 'Off');
            end
        end

        % Value changed function: TrybSwitch
        function TrybSwitchValueChanged(app, event)
            modelName = 'DT';
            disp(app.TrybSwitch.Value)

            if strcmp(app.TrybSwitch.Value, 'Monitorowanie')
                newValue = false; % Use logical true/false for boolean
            else
                newValue = true;
            end
            
            % --- 3. EXECUTE THE "UPDATE FROM WORKSPACE" METHOD ---
            try
                % First, verify the model is actually running in the correct mode
                simStatus = get_param(modelName, 'SimulationStatus');
                if ~strcmp(simStatus, 'running')
                    errorMessage = 'Model is not running in running Mode. This command only works on an active simulation.';
                    uialert(app.UIFigure, errorMessage, 'Error');
                    disp(errorMessage);
                    return; % Stop here
                end
        
                % Change the .Value property of the 'R8' object in the BASE workspace
                assignin('base', 'sterowanie', newValue);

                % This command tells the running model to re-read 'R8' from the workspace.
                set_param(modelName, 'SimulationCommand', 'update');
                
                disp('Workspace parameter updated and simulation update command sent.');
        
            catch ME
                % If something goes wrong, this will give you a detailed error
                uialert(app.UIFigure, ['An error occurred: ' ME.message], 'Error!');
                fprintf('\n--- APP DESIGNER ERROR ---\n');
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
            end
            
        end

        % Value changed function: OnlineSwitch
        function OnlineSwitchValueChanged(app, event)
            modelName = 'DT';
            disp(app.TrybSwitch.Value)

            if strcmp(app.OnlineSwitch.Value, 'Offline')
                newValue = false; % Use logical true/false for boolean
            else
                newValue = true;
            end
            
            % --- 3. EXECUTE THE "UPDATE FROM WORKSPACE" METHOD ---
            try
                % First, verify the model is actually running in the correct mode
                simStatus = get_param(modelName, 'SimulationStatus');
                if ~strcmp(simStatus, 'running')
                    errorMessage = 'Model is not running in running Mode. This command only works on an active simulation.';
                    uialert(app.UIFigure, errorMessage, 'Error');
                    disp(errorMessage);
                    return; % Stop here
                end
        
                % Change the .Value property of the 'R8' object in the BASE workspace
                assignin('base', 'online', newValue);

                % This command tells the running model to re-read 'R8' from the workspace.
                set_param(modelName, 'SimulationCommand', 'update');
                
                disp('Workspace parameter updated and simulation update command sent.');
        
            catch ME
                % If something goes wrong, this will give you a detailed error
                uialert(app.UIFigure, ['An error occurred: ' ME.message], 'Error!');
                fprintf('\n--- APP DESIGNER ERROR ---\n');
                disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.WizualizacjaPanel = uipanel(app.UIFigure);
            app.WizualizacjaPanel.Title = 'Wizualizacja';
            app.GridLayout = uigridlayout(app.WizualizacjaPanel);
            app.Pipe_pump2 = uiaxes(app.GridLayout);
            app.Pipe_pump2.XTick = [];
            app.Pipe_pump2.YTick = [];
            app.Pipe_pump = uiaxes(app.GridLayout);
            app.Pipe_pump.XTick = [];
            app.Pipe_pump.YTick = [];
            app.UIAxes_4 = uiaxes(app.GridLayout);
            title(app.UIAxes_4, 'Title')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, 'Title')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.Pipe_R2_horizontal3 = uiaxes(app.GridLayout);
            app.Pipe_R2_horizontal3.XTick = [];
            app.Pipe_R2_horizontal3.YTick = [];
            app.Pipe_R7_horizontal = uiaxes(app.GridLayout);
            app.Pipe_R7_horizontal.XTick = [];
            app.Pipe_R7_horizontal.YTick = [];
            app.Pipe_R7_horizontal2 = uiaxes(app.GridLayout);
            app.Pipe_R7_horizontal2.XTick = [];
            app.Pipe_R7_horizontal2.YTick = [];
            app.Pipe_R7_vertical = uiaxes(app.GridLayout);
            app.Pipe_R7_vertical.XTick = [];
            app.Pipe_R7_vertical.YTick = [];
            app.Pipe_R8_horizontal2 = uiaxes(app.GridLayout);
            app.Pipe_R8_horizontal2.XTick = [];
            app.Pipe_R8_horizontal2.YTick = [];
            app.Pipe_R8_vertical = uiaxes(app.GridLayout);
            app.Pipe_R8_vertical.XTick = [];
            app.Pipe_R8_vertical.YTick = [];
            app.Pipe_R8_horizontal = uiaxes(app.GridLayout);
            app.Pipe_R8_horizontal.XTick = [];
            app.Pipe_R8_horizontal.YTick = [];
            app.Pipe_R1_vertical = uiaxes(app.GridLayout);
            app.Pipe_R1_vertical.XTick = [];
            app.Pipe_R1_vertical.YTick = [];
            app.Pipe_R1_horizontal = uiaxes(app.GridLayout);
            app.Pipe_R1_horizontal.XTick = [];
            app.Pipe_R1_horizontal.YTick = [];
            app.Pipe_R3_vertical2 = uiaxes(app.GridLayout);
            app.Pipe_R3_vertical2.XTick = [];
            app.Pipe_R3_vertical2.YTick = [];
            app.Pipe_R3_vertical = uiaxes(app.GridLayout);
            app.Pipe_R3_vertical.XTick = [];
            app.Pipe_R3_vertical.YTick = [];
            app.Pipe_R2_horizontal2 = uiaxes(app.GridLayout);
            app.Pipe_R2_horizontal2.XTick = [];
            app.Pipe_R2_horizontal2.YTick = [];
            app.Pipe_R2_horizontal = uiaxes(app.GridLayout);
            app.Pipe_R2_horizontal.XTick = [];
            app.Pipe_R2_horizontal.YTick = [];
            app.Pipe_R1_vertical3 = uiaxes(app.GridLayout);
            app.Pipe_R1_vertical3.XTick = [];
            app.Pipe_R1_vertical3.YTick = [];
            app.Pipe_R1_vertical2 = uiaxes(app.GridLayout);
            app.Pipe_R1_vertical2.XTick = [];
            app.Pipe_R1_vertical2.YTick = [];
            app.Image_R7_red = uiimage(app.GridLayout);
            app.Image_R7_red.ImageSource = fullfile(pathToMLAPP, 'valve_red_horizontal.png');
            app.Image_R7_green = uiimage(app.GridLayout);
            app.Image_R7_green.ImageSource = fullfile(pathToMLAPP, 'valve_green_horizontal.png');
            app.Image_R1 = uiimage(app.GridLayout);
            app.Image_R1.ImageSource = fullfile(pathToMLAPP, 'valve_black.png');
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'valve_red.png');
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'valve_green.png');
            app.Image_R5_red = uiimage(app.GridLayout);
            app.Image_R5_red.ImageSource = fullfile(pathToMLAPP, 'valve_red.png');
            app.Image_R5_green = uiimage(app.GridLayout);
            app.Image_R5_green.ImageSource = fullfile(pathToMLAPP, 'valve_green.png');
            app.Image_R2 = uiimage(app.GridLayout);
            app.Image_R2.ImageSource = fullfile(pathToMLAPP, 'valve_black_horizontal.png');
            app.Image_R6_red = uiimage(app.GridLayout);
            app.Image_R6_red.ImageSource = fullfile(pathToMLAPP, 'valve_red_horizontal.png');
            app.Image_R8_red = uiimage(app.GridLayout);
            app.Image_R8_red.ImageSource = fullfile(pathToMLAPP, 'valve_red_horizontal.png');
            app.Image_R8_green = uiimage(app.GridLayout);
            app.Image_R8_green.ImageSource = fullfile(pathToMLAPP, 'valve_green_horizontal.png');
            app.Image_R6_green = uiimage(app.GridLayout);
            app.Image_R6_green.ImageSource = fullfile(pathToMLAPP, 'valve_green_horizontal.png');
            app.R7Label = uilabel(app.GridLayout);
            app.R7Label.Text = 'R7';
            app.R6Label = uilabel(app.GridLayout);
            app.R6Label.Text = 'R6';
            app.R2Label = uilabel(app.GridLayout);
            app.R2Label.Text = 'R2';
            app.R4Label = uilabel(app.GridLayout);
            app.R4Label.Text = 'R4';
            app.R1Label_2 = uilabel(app.GridLayout);
            app.R1Label_2.Text = 'R1';
            app.R5Label = uilabel(app.GridLayout);
            app.R5Label.Text = 'R5';
            app.R3Label = uilabel(app.GridLayout);
            app.R3Label.Text = 'R3';
            app.Image3 = uiimage(app.GridLayout);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'pump.png');
            app.SterowaniePanel = uipanel(app.UIFigure);
            app.SterowaniePanel.Title = 'Sterowanie';
            app.R3SwitchLabel = uilabel(app.SterowaniePanel);
            app.R3SwitchLabel.Text = 'R3';
            app.R3Switch = uiswitch(app.SterowaniePanel, 'slider');
            app.R3Switch.ValueChangedFcn = createCallbackFcn(app, @R3SwitchValueChanged, true);
            app.R5SwitchLabel = uilabel(app.SterowaniePanel);
            app.R5SwitchLabel.Text = 'R5';
            app.R5Switch = uiswitch(app.SterowaniePanel, 'slider');
            app.R5Switch.ValueChangedFcn = createCallbackFcn(app, @R5SwitchValueChanged, true);
            app.R6SwitchLabel = uilabel(app.SterowaniePanel);
            app.R6SwitchLabel.Text = 'R6';
            app.R6Switch = uiswitch(app.SterowaniePanel, 'slider');
            app.R6Switch.ValueChangedFcn = createCallbackFcn(app, @R6SwitchValueChanged, true);
            app.R4SwitchLabel = uilabel(app.SterowaniePanel);
            app.R4SwitchLabel.Text = 'R4';
            app.R4Switch = uiswitch(app.SterowaniePanel, 'slider');
            app.R4Switch.ValueChangedFcn = createCallbackFcn(app, @R4SwitchValueChanged, true);
            app.R7SwitchLabel = uilabel(app.SterowaniePanel);
            app.R7SwitchLabel.Text = 'R7';
            app.R7Switch = uiswitch(app.SterowaniePanel, 'slider');
            app.R7Switch.ValueChangedFcn = createCallbackFcn(app, @R7SwitchValueChanged, true);
            app.sterowanieR1EditFieldLabel = uilabel(app.SterowaniePanel);
            app.sterowanieR1EditFieldLabel.Text = 'sterowanie R1 [%]';
            app.sterowanieR1EditField = uieditfield(app.SterowaniePanel, 'numeric');
            app.sterowanieR1EditField.Limits = [0 100];
            app.sterowanieR1EditField.ValueChangedFcn = createCallbackFcn(app, @sterowanieR1EditFieldValueChanged, true);
            app.sterowanieR2EditFieldLabel = uilabel(app.SterowaniePanel);
            app.sterowanieR2EditFieldLabel.Text = 'sterowanie R2 [%]';
            app.sterowanieR2EditField = uieditfield(app.SterowaniePanel, 'numeric');
            app.sterowanieR2EditField.Limits = [0 100];
            app.sterowanieR2EditField.ValueChangedFcn = createCallbackFcn(app, @sterowanieR2EditFieldValueChanged, true);
            app.sterowaniepompyLabel = uilabel(app.SterowaniePanel);
            app.sterowaniepompyLabel.Text = 'sterowanie pompy [%]';
            app.sterowaniepompyEditField = uieditfield(app.SterowaniePanel, 'numeric');
            app.sterowaniepompyEditField.Limits = [0 100];
            app.sterowaniepompyEditField.ValueChangedFcn = createCallbackFcn(app, @sterowaniepompyEditFieldValueChanged, true);
            app.AlarmyPanel = uipanel(app.UIFigure);
            app.AlarmyPanel.Title = 'Alarmy';
            app.PrzelanieLamp = uilamp(app.AlarmyPanel);
            app.PrzelanieText = uilabel(app.AlarmyPanel);
            app.PrzelanieText.Text = 'Zabezpieczenie przed przelaniem - pompa wyłączona';
            app.NiskiPoziomLamp = uilamp(app.AlarmyPanel);
            app.NiskiPoziomText = uilabel(app.AlarmyPanel);
            app.NiskiPoziomText.Text = 'Niski poziom cieczy   w A3 - ryzyko zapowietrzenia pompy';
            app.ZaworyZamknieteLamp = uilamp(app.AlarmyPanel);
            app.ZaworyZamknieteText = uilabel(app.AlarmyPanel);
            app.ZaworyZamknieteText.Text = 'Pompa włączona i brak możliwej drogi przepływu';
            app.WykresyPanel = uipanel(app.UIFigure);
            app.WykresyPanel.Title = 'Wykresy';
            app.water_levels_plot = uiaxes(app.WykresyPanel);
            title(app.water_levels_plot, 'Poziomy cieczy w zbiornikach')
            xlabel(app.water_levels_plot, 'czas [s]')
            ylabel(app.water_levels_plot, 'poziom cieczy [%]')
            zlabel(app.water_levels_plot, 'Z')
            app.A1CheckBox = uicheckbox(app.WykresyPanel);
            app.A1CheckBox.ValueChangedFcn = createCallbackFcn(app, @A1CheckBoxValueChanged, true);
            app.A1CheckBox.Text = 'A1';
            app.A2CheckBox = uicheckbox(app.WykresyPanel);
            app.A2CheckBox.ValueChangedFcn = createCallbackFcn(app, @A2CheckBoxValueChanged, true);
            app.A2CheckBox.Text = 'A2';
            app.A3CheckBox = uicheckbox(app.WykresyPanel);
            app.A3CheckBox.ValueChangedFcn = createCallbackFcn(app, @A3CheckBoxValueChanged, true);
            app.A3CheckBox.Text = 'A3';
            app.TrybpracyPanel = uipanel(app.UIFigure);
            app.TrybpracyPanel.Title = 'Tryb pracy';
            app.TrybSwitchLabel = uilabel(app.TrybpracyPanel);
            app.TrybSwitchLabel.Text = 'Tryb';
            app.TrybSwitch = uiswitch(app.TrybpracyPanel, 'slider');
            app.TrybSwitch.Items = {'Monitorowanie', 'Sterowanie'};
            app.TrybSwitch.ValueChangedFcn = createCallbackFcn(app, @TrybSwitchValueChanged, true);
            app.TrybSwitch.Value = 'Monitorowanie';
            app.PoczeniezfizycznymbliniakiemSwitchLabel = uilabel(app.TrybpracyPanel);
            app.PoczeniezfizycznymbliniakiemSwitchLabel.Text = 'Połączenie z fizycznym bliźniakiem';
            app.OnlineSwitch = uiswitch(app.TrybpracyPanel, 'slider');
            app.OnlineSwitch.Items = {'Offline', 'Online'};
            app.OnlineSwitch.ValueChangedFcn = createCallbackFcn(app, @OnlineSwitchValueChanged, true);
            app.OnlineSwitch.Value = 'Online';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DT_app

            % Associate the Simulink Model
            app.Simulation = simulation('DT');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end